classdef Interface1_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uipanel1     matlab.ui.container.Panel
        text4_3      matlab.ui.control.Label
        text4_2      matlab.ui.control.Label
        text2_2      matlab.ui.control.Label
        pushbutton8  matlab.ui.control.Button
        pushbutton7  matlab.ui.control.Button
        pushbutton6  matlab.ui.control.Button
        pushbutton5  matlab.ui.control.Button
        pushbutton4  matlab.ui.control.Button
        pushbutton3  matlab.ui.control.Button
        pushbutton2  matlab.ui.control.Button
        pushbutton1  matlab.ui.control.Button
        text6        matlab.ui.control.Label
        text5        matlab.ui.control.Label
        text4        matlab.ui.control.Label
        text3        matlab.ui.control.Label
        text2        matlab.ui.control.Label
        uipanel2     matlab.ui.container.Panel
        Label_5      matlab.ui.control.Label
        Label_6      matlab.ui.control.Label
        Label_4      matlab.ui.control.Label
        Label        matlab.ui.control.Label
        Label_7      matlab.ui.control.Label
        Label_8      matlab.ui.control.Label
        Label_3      matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        Lamp_8       matlab.ui.control.Lamp
        Lamp_7       matlab.ui.control.Lamp
        Lamp_6       matlab.ui.control.Lamp
        Lamp_5       matlab.ui.control.Lamp
        Lamp_4       matlab.ui.control.Lamp
        Lamp_3       matlab.ui.control.Lamp
        Lamp_2       matlab.ui.control.Lamp
        Lamp         matlab.ui.control.Lamp
        Image        matlab.ui.control.Image
        edit4        matlab.ui.control.EditField
        edit3        matlab.ui.control.EditField
        checkbox2    matlab.ui.control.CheckBox
        checkbox1    matlab.ui.control.CheckBox
        edit2        matlab.ui.control.EditField
        edit1        matlab.ui.control.EditField
        axes8        matlab.ui.control.UIAxes
        axes7        matlab.ui.control.UIAxes
        axes6        matlab.ui.control.UIAxes
        axes5        matlab.ui.control.UIAxes
        axes4        matlab.ui.control.UIAxes
        axes3        matlab.ui.control.UIAxes
        axes2        matlab.ui.control.UIAxes
        axes1        matlab.ui.control.UIAxes
        ContextMenu  matlab.ui.container.ContextMenu
        Menu         matlab.ui.container.Menu
        Menu2        matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Interface1_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Interface1 (see VARARGIN)
            
            % Choose default command line output for Interface1
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes Interface1 wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
delete(instrfind({'Port'},{'COM3'}));
puerto=serial('COM3');
puerto.BaudRate=9600;
warning('off','MATLAB:Serial:fscanf:unsuccesfulRead');
fopen(puerto);

clear i

    for i=1:10
    valor = str2num(fscanf(puerto))

       axes(handles.axes1) 
       plot(i,valor(1),'*r')
        hold on
       axes(handles.axes2) 
       plot(i,valor(2),'*b')
        hold on
       axes(handles.axes3) 
       plot(i,valor(3),'*y')
       hold on
       axes(handles.axes4) 
       plot(i,valor(4),'*g')
       hold on
       axes(handles.axes5) 
       plot(i,valor(5),'*r')
        hold on
       axes(handles.axes6) 
       plot(i,valor(6),'*b')
        hold on
       axes(handles.axes7) 
       plot(i,valor(7),'*g')
       hold on
       axes(handles.axes8) 
       plot(i,valor(8),'*y')
       hold on

     drawnow
    end
    hold off

fclose(puerto)


%             axes(handles.axes8)
%             plot(t,V1(8,:))
fclose(puerto);
delete(puerto)
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.Lamp.Color = 'red'
          
            pause(1)
            app.Lamp.Color = 'white'
         
        end

        % Button pushed function: pushbutton2
        function pushbutton2ButtonPushed(app, event)
            app.Lamp_2.Color = 'red'
           
            pause(1)
            app.Lamp_2.Color = 'white'
          
        end

        % Button pushed function: pushbutton3
        function pushbutton3ButtonPushed(app, event)
             app.Lamp_3.Color = 'red'
         
            pause(1)
            app.Lamp_3.Color = 'white'
          
        end

        % Button pushed function: pushbutton4
        function pushbutton4ButtonPushed(app, event)
            app.Lamp_4.Color = 'red'
         
            pause(1)
            app.Lamp_4.Color = 'white'
          

        end

        % Button pushed function: pushbutton5
        function pushbutton5ButtonPushed(app, event)
            app.Lamp_5.Color = 'red'
           
            pause(1)
            app.Lamp_5.Color = 'white'
         
        end

        % Button pushed function: pushbutton6
        function pushbutton6ButtonPushed(app, event)
             app.Lamp_6.Color = 'red'
           
            pause(1)
            app.Lamp_6.Color = 'white'
          
        end

        % Button pushed function: pushbutton7
        function pushbutton7ButtonPushed(app, event)
             app.Lamp_7.Color = 'red'
           
            pause(1)
            app.Lamp_7.Color = 'white'
        
        end

        % Button pushed function: pushbutton8
        function pushbutton8ButtonPushed(app, event)
             app.Lamp_8.Color = 'red'
       
            pause(1)
            app.Lamp_8.Color = 'white'
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Interface1';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Panel';
            app.uipanel1.Tag = 'uipanel1';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.uipanel1);
            app.axes2.Tag = 'axes2';
            app.axes3 = uiaxes(app.uipanel1);
            app.axes3.Tag = 'axes3';
            app.axes4 = uiaxes(app.uipanel1);
            app.axes4.Tag = 'axes4';
            app.axes5 = uiaxes(app.uipanel1);
            app.axes5.Tag = 'axes5';
            app.axes6 = uiaxes(app.uipanel1);
            app.axes6.Tag = 'axes6';
            app.axes7 = uiaxes(app.uipanel1);
            app.axes7.Tag = 'axes7';
            app.axes8 = uiaxes(app.uipanel1);
            app.axes8.Tag = 'axes8';
            app.edit1 = uieditfield(app.uipanel1, 'text');
            app.edit1.Tag = 'edit1';
            app.edit2 = uieditfield(app.uipanel1, 'text');
            app.edit2.Tag = 'edit2';
            app.checkbox1 = uicheckbox(app.uipanel1);
            app.checkbox1.Tag = 'checkbox1';
            app.checkbox1.Text = 'M';
            app.checkbox2 = uicheckbox(app.uipanel1);
            app.checkbox2.Tag = 'checkbox2';
            app.checkbox2.Text = 'F';
            app.edit3 = uieditfield(app.uipanel1, 'text');
            app.edit3.Tag = 'edit3';
            app.edit3.Value = ' ';
            app.edit4 = uieditfield(app.uipanel1, 'text');
            app.edit4.Tag = 'edit4';
            app.uipanel2 = uipanel(app.uipanel1);
            app.uipanel2.Title = 'ESQUEMA DE DISTRIBUCION DE LOS SENSORES';
            app.uipanel2.Tag = 'uipanel2';
            app.Image = uiimage(app.uipanel2);
            app.Image.ImageSource = 'po.PNG';
            app.Lamp = uilamp(app.uipanel2);
            app.Lamp_2 = uilamp(app.uipanel2);
            app.Lamp_3 = uilamp(app.uipanel2);
            app.Lamp_4 = uilamp(app.uipanel2);
            app.Lamp_5 = uilamp(app.uipanel2);
            app.Lamp_6 = uilamp(app.uipanel2);
            app.Lamp_7 = uilamp(app.uipanel2);
            app.Lamp_8 = uilamp(app.uipanel2);
            app.Label_2 = uilabel(app.uipanel2);
            app.Label_2.Text = '02';
            app.Label_3 = uilabel(app.uipanel2);
            app.Label_3.Text = '03';
            app.Label_8 = uilabel(app.uipanel2);
            app.Label_8.Text = '04';
            app.Label_7 = uilabel(app.uipanel2);
            app.Label_7.Text = '08';
            app.Label = uilabel(app.uipanel2);
            app.Label.Text = '01';
            app.Label_4 = uilabel(app.uipanel2);
            app.Label_4.Text = '05';
            app.Label_6 = uilabel(app.uipanel2);
            app.Label_6.Text = '07';
            app.Label_5 = uilabel(app.uipanel2);
            app.Label_5.Text = '06';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'INGRESE EL NOMBRE';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'SELECCIONE EL GENERO';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'INGRESE LA EDAD';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'CLASIFICACION DEL TIPO DE PISADA';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'RECOMENDACIONES PROFESIONALES';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'SENSOR 01';
            app.pushbutton2 = uibutton(app.uipanel1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2ButtonPushed, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = 'SENSOR 02';
            app.pushbutton3 = uibutton(app.uipanel1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3ButtonPushed, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = 'SENSOR 03';
            app.pushbutton4 = uibutton(app.uipanel1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4ButtonPushed, true);
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Text = 'SENSOR 04';
            app.pushbutton5 = uibutton(app.uipanel1, 'push');
            app.pushbutton5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton5ButtonPushed, true);
            app.pushbutton5.Tag = 'pushbutton5';
            app.pushbutton5.Text = 'SENSOR 05';
            app.pushbutton6 = uibutton(app.uipanel1, 'push');
            app.pushbutton6.ButtonPushedFcn = createCallbackFcn(app, @pushbutton6ButtonPushed, true);
            app.pushbutton6.Tag = 'pushbutton6';
            app.pushbutton6.Text = 'SENSOR 06';
            app.pushbutton7 = uibutton(app.uipanel1, 'push');
            app.pushbutton7.ButtonPushedFcn = createCallbackFcn(app, @pushbutton7ButtonPushed, true);
            app.pushbutton7.Tag = 'pushbutton7';
            app.pushbutton7.Text = 'SENSOR 07';
            app.pushbutton8 = uibutton(app.uipanel1, 'push');
            app.pushbutton8.ButtonPushedFcn = createCallbackFcn(app, @pushbutton8ButtonPushed, true);
            app.pushbutton8.Tag = 'pushbutton8';
            app.pushbutton8.Text = 'SENSOR 08';
            app.text2_2 = uilabel(app.uipanel1);
            app.text2_2.Tag = 'text2';
            app.text2_2.Text = 'CARRERA';
            app.text4_2 = uilabel(app.uipanel1);
            app.text4_2.Tag = 'text4';
            app.text4_2.Text = 'ESTUDIANTES';
            app.text4_3 = uilabel(app.uipanel1);
            app.text4_3.Tag = 'text4';
            app.text4_3.Text = 'UNIVERSIDAD';
            app.ContextMenu = uicontextmenu(app.figure1);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface1_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Interface1_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end